classdef bh_4bar_demo_launcher_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        bh_4bar_content_APPUIFigure    matlab.ui.Figure
        barMechanismContentLauncherLabel  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        WELCOMETab                     matlab.ui.container.Tab
        WelcometothisTutorialontheKinematicsandDynamicsoftheLabel  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        WeregoingtolokkspecificallyattheLabel  matlab.ui.control.Label
        SYSTEM_CHECKTab                matlab.ui.container.Tab
        PRODUCTSPanel                  matlab.ui.container.Panel
        DoyouhavetherequiredproductsneededforthistutorialLabel  matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        SYS_CHECK_But                  matlab.ui.control.Button
        SimscapeMultibodyLampLabel     matlab.ui.control.Label
        LAMP_SCMB                      matlab.ui.control.Lamp
        SimscapeLampLabel              matlab.ui.control.Label
        LAMP_SC                        matlab.ui.control.Lamp
        SimulinkLampLabel              matlab.ui.control.Label
        LAMP_SL                        matlab.ui.control.Lamp
        SymbolicMathToolboxLampLabel   matlab.ui.control.Label
        LAMP_SYMT                      matlab.ui.control.Lamp
        MATLABLampLabel                matlab.ui.control.Label
        LAMP_ML                        matlab.ui.control.Lamp
        START_HERETab                  matlab.ui.container.Tab
        SH_But_explorer_app            matlab.ui.control.Button
        SH_Lamp_APP_progress_1         matlab.ui.control.Lamp
        SH_Lamp_APP_progress_2         matlab.ui.control.Lamp
        SH_Lamp_APP_progress_3         matlab.ui.control.Lamp
        SH_Lamp_APP_progress_4         matlab.ui.control.Lamp
        SH_Lamp_APP_progress_5         matlab.ui.control.Lamp
        SH_But_Dynamic_Derivation      matlab.ui.control.Button
        SH_But_kinematics_derivation   matlab.ui.control.Button
        SH_But_Kinematics_example      matlab.ui.control.Button
        SH_But_Dynamic_example         matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        TogetstartedHavealookatthefollowingexamplesLabel  matlab.ui.control.Label
        KINEMATICSTab                  matlab.ui.container.Tab
        KIN_01_Knob                    matlab.ui.control.DiscreteKnob
        ChooseyourKinematictopicLabel  matlab.ui.control.Label
        DYNAMICSTab                    matlab.ui.container.Tab
        DYN_01_Knob                    matlab.ui.control.DiscreteKnob
        ChooseyourDynamicstopicLabel   matlab.ui.control.Label
        APPSTab                        matlab.ui.container.Tab
        TabGroup2                      matlab.ui.container.TabGroup
        THE_KINEMATICS_APPTab          matlab.ui.container.Tab
        TheKinematicsAPPButton         matlab.ui.control.Button
        Image2                         matlab.ui.control.Image
        ExploretheAngularkinematicsofthe4barMechanismLabel  matlab.ui.control.Label
        Lamp_APP_progress_1            matlab.ui.control.Lamp
        Lamp_APP_progress_2            matlab.ui.control.Lamp
        Lamp_APP_progress_3            matlab.ui.control.Lamp
        Lamp_APP_progress_4            matlab.ui.control.Lamp
        Lamp_APP_progress_5            matlab.ui.control.Lamp
    end

    
    properties (Access = public)
       DS_OBJ % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DS_OBJ = bh_4bar_demo_selector_CLS;
        end

        % Button pushed function: SYS_CHECK_But
        function SYS_CHECK_ButButtonPushed(app, event)
            app.DS_OBJ.do_system_check(app);
        end

        % Value changed function: KIN_01_Knob
        function KIN_01_KnobValueChanged(app, event)
            value = app.KIN_01_Knob.Value;
            app.DS_OBJ.execute_KIN_01_KNOB_selection(value);
        end

        % Value changed function: DYN_01_Knob
        function DYN_01_KnobValueChanged(app, event)
            value = app.DYN_01_Knob.Value;
            app.DS_OBJ.execute_DYN_01_KNOB_selection(value);
        end

        % Button pushed function: TheKinematicsAPPButton
        function TheKinematicsAPPButtonPushed(app, event)
                DEFAULT_COLOR = [255 191 0]/255; % orange
                GO_COLOR      = [0   255 0]/255; % GREEN
                
                app.Lamp_APP_progress_1.Color = DEFAULT_COLOR;
                app.Lamp_APP_progress_2.Color = DEFAULT_COLOR;            
                app.Lamp_APP_progress_3.Color = DEFAULT_COLOR;            
                app.Lamp_APP_progress_4.Color = DEFAULT_COLOR;            
                app.Lamp_APP_progress_5.Color = DEFAULT_COLOR;
 
                app.Lamp_APP_progress_1.Color = GO_COLOR; %pause(0.1);
                app.Lamp_APP_progress_2.Color = GO_COLOR; %pause(0.1);            
                app.Lamp_APP_progress_3.Color = GO_COLOR; pause(0.1);        
                
                % launch the APP ... sometimes it takes a second or 2 to
                % open
                app.DS_OBJ.execute_kinematics_app_BUTTON();
               
                app.Lamp_APP_progress_4.Color = GO_COLOR; %pause(0.1);            
                app.Lamp_APP_progress_5.Color = GO_COLOR; pause(0.1);
                                
        end

        % Button pushed function: SH_But_explorer_app
        function SH_But_explorer_appButtonPushed(app, event)
                DEFAULT_COLOR = [255 191 0]/255; % orange
                GO_COLOR      = [0   255 0]/255; % GREEN
                
                app.SH_Lamp_APP_progress_1.Color = DEFAULT_COLOR;
                app.SH_Lamp_APP_progress_2.Color = DEFAULT_COLOR;            
                app.SH_Lamp_APP_progress_3.Color = DEFAULT_COLOR;            
                app.SH_Lamp_APP_progress_4.Color = DEFAULT_COLOR;            
                app.SH_Lamp_APP_progress_5.Color = DEFAULT_COLOR;
 
                app.SH_Lamp_APP_progress_1.Color = GO_COLOR; %pause(0.1);
                app.SH_Lamp_APP_progress_2.Color = GO_COLOR; %pause(0.1);            
                app.SH_Lamp_APP_progress_3.Color = GO_COLOR; pause(0.1);        
                
                % launch the APP ... sometimes it takes a second or 2 to
                % open
                app.DS_OBJ.execute_start_here("THE 4BAR EXPLORER APP");
               
                app.SH_Lamp_APP_progress_4.Color = GO_COLOR; %pause(0.2);            
                app.SH_Lamp_APP_progress_5.Color = GO_COLOR; pause(0.1);            
        end

        % Button pushed function: SH_But_kinematics_derivation
        function SH_But_kinematics_derivationButtonPushed(app, event)
            app.DS_OBJ.execute_start_here("KINEMATIC_DERIVATION_CIRCLES");
        end

        % Button pushed function: SH_But_Kinematics_example
        function SH_But_Kinematics_exampleButtonPushed(app, event)
            app.DS_OBJ.execute_start_here("KINEMATIC_EXAMPLE");
        end

        % Button pushed function: SH_But_Dynamic_Derivation
        function SH_But_Dynamic_DerivationButtonPushed(app, event)
           app.DS_OBJ.execute_start_here("DYNAMIC_DERIVATION_LAGCONSTRAINTS"); 
        end

        % Button pushed function: SH_But_Dynamic_example
        function SH_But_Dynamic_exampleButtonPushed(app, event)
             app.DS_OBJ.execute_start_here("DYNAMIC_EXAMPLE_SIMULATION"); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.bh_4bar_content_APPUIFigure = uifigure('Visible', 'off');
            app.bh_4bar_content_APPUIFigure.Name = 'bh_4bar_content_APP';
            app.barMechanismContentLauncherLabel = uilabel(app.bh_4bar_content_APPUIFigure);
            app.barMechanismContentLauncherLabel.Text = '4bar Mechanism - Content Launcher';
            app.TabGroup = uitabgroup(app.bh_4bar_content_APPUIFigure);
            app.WELCOMETab = uitab(app.TabGroup);
            app.WELCOMETab.Title = 'WELCOME';
            app.WelcometothisTutorialontheKinematicsandDynamicsoftheLabel = uilabel(app.WELCOMETab);
            app.WelcometothisTutorialontheKinematicsandDynamicsoftheLabel.Text = 'Welcome to this MATLAB and Simulink Tutorial on the 4bar Mechanism';
            app.Image = uiimage(app.WELCOMETab);
            app.Image.ImageSource = 'bh_4bar_SCHEM_FOR_APP.PNG';
            app.WeregoingtolokkspecificallyattheLabel = uilabel(app.WELCOMETab);
            app.WeregoingtolokkspecificallyattheLabel.Text = {'We''re going to look specifically at the KINEMATICS and DYNAMICS'; 'of this Mechanism '};
            app.SYSTEM_CHECKTab = uitab(app.TabGroup);
            app.SYSTEM_CHECKTab.Title = 'SYSTEM_CHECK';
            app.PRODUCTSPanel = uipanel(app.SYSTEM_CHECKTab);
            app.PRODUCTSPanel.Title = 'PRODUCTS';
            app.DoyouhavetherequiredproductsneededforthistutorialLabel = uilabel(app.PRODUCTSPanel);
            app.DoyouhavetherequiredproductsneededforthistutorialLabel.Text = 'Do you have the required products needed for this tutorial ?';
            app.TextArea = uitextarea(app.PRODUCTSPanel);
            app.TextArea.Value = {'If you see any RED lamps, you may not be able to run some of the demos.'};
            app.SYS_CHECK_But = uibutton(app.PRODUCTSPanel, 'push');
            app.SYS_CHECK_But.ButtonPushedFcn = createCallbackFcn(app, @SYS_CHECK_ButButtonPushed, true);
            app.SYS_CHECK_But.Text = 'CLICK ME to test your installation';
            app.SimscapeMultibodyLampLabel = uilabel(app.PRODUCTSPanel);
            app.SimscapeMultibodyLampLabel.Text = 'Simscape Multibody';
            app.LAMP_SCMB = uilamp(app.PRODUCTSPanel);
            app.SimscapeLampLabel = uilabel(app.PRODUCTSPanel);
            app.SimscapeLampLabel.Text = 'Simscape';
            app.LAMP_SC = uilamp(app.PRODUCTSPanel);
            app.SimulinkLampLabel = uilabel(app.PRODUCTSPanel);
            app.SimulinkLampLabel.Text = 'Simulink';
            app.LAMP_SL = uilamp(app.PRODUCTSPanel);
            app.SymbolicMathToolboxLampLabel = uilabel(app.PRODUCTSPanel);
            app.SymbolicMathToolboxLampLabel.Text = 'Symbolic Math Toolbox';
            app.LAMP_SYMT = uilamp(app.PRODUCTSPanel);
            app.MATLABLampLabel = uilabel(app.PRODUCTSPanel);
            app.MATLABLampLabel.Text = 'MATLAB';
            app.LAMP_ML = uilamp(app.PRODUCTSPanel);
            app.START_HERETab = uitab(app.TabGroup);
            app.START_HERETab.Title = 'START_HERE';
            app.SH_But_explorer_app = uibutton(app.START_HERETab, 'push');
            app.SH_But_explorer_app.ButtonPushedFcn = createCallbackFcn(app, @SH_But_explorer_appButtonPushed, true);
            app.SH_But_explorer_app.Text = 'The 4bar explorer APP';
            app.SH_Lamp_APP_progress_1 = uilamp(app.START_HERETab);
            app.SH_Lamp_APP_progress_2 = uilamp(app.START_HERETab);
            app.SH_Lamp_APP_progress_3 = uilamp(app.START_HERETab);
            app.SH_Lamp_APP_progress_4 = uilamp(app.START_HERETab);
            app.SH_Lamp_APP_progress_5 = uilamp(app.START_HERETab);
            app.SH_But_Dynamic_Derivation = uibutton(app.START_HERETab, 'push');
            app.SH_But_Dynamic_Derivation.ButtonPushedFcn = createCallbackFcn(app, @SH_But_Dynamic_DerivationButtonPushed, true);
            app.SH_But_Dynamic_Derivation.Text = 'Dynamic_Derivation_LagContraints';
            app.SH_But_kinematics_derivation = uibutton(app.START_HERETab, 'push');
            app.SH_But_kinematics_derivation.ButtonPushedFcn = createCallbackFcn(app, @SH_But_kinematics_derivationButtonPushed, true);
            app.SH_But_kinematics_derivation.Text = 'Kinematic_Derivation_Circles';
            app.SH_But_Kinematics_example = uibutton(app.START_HERETab, 'push');
            app.SH_But_Kinematics_example.ButtonPushedFcn = createCallbackFcn(app, @SH_But_Kinematics_exampleButtonPushed, true);
            app.SH_But_Kinematics_example.Text = 'Kinematic_example';
            app.SH_But_Dynamic_example = uibutton(app.START_HERETab, 'push');
            app.SH_But_Dynamic_example.ButtonPushedFcn = createCallbackFcn(app, @SH_But_Dynamic_exampleButtonPushed, true);
            app.SH_But_Dynamic_example.Text = 'Dynamic_example_simulation';
            app.Label = uilabel(app.START_HERETab);
            app.Label.Text = '1.) ';
            app.Label_2 = uilabel(app.START_HERETab);
            app.Label_2.Text = '2.) ';
            app.Label_3 = uilabel(app.START_HERETab);
            app.Label_3.Text = '3.) ';
            app.Label_4 = uilabel(app.START_HERETab);
            app.Label_4.Text = '4.) ';
            app.Label_5 = uilabel(app.START_HERETab);
            app.Label_5.Text = '5.) ';
            app.TogetstartedHavealookatthefollowingexamplesLabel = uilabel(app.START_HERETab);
            app.TogetstartedHavealookatthefollowingexamplesLabel.Text = {'To get started'; 'Have a look at the following examples'};
            app.KINEMATICSTab = uitab(app.TabGroup);
            app.KINEMATICSTab.Title = 'KINEMATICS';
            app.KIN_01_Knob = uiknob(app.KINEMATICSTab, 'discrete');
            app.KIN_01_Knob.Items = {'Nothing', 'Kinematic_Derivation', 'Kinematic_Derivation_Alternate', 'Explore_Singularities', 'Explore_Singularities_PART_2', 'THE_APP', 'Kinematic_Simulation_Model', 'Velocity_Analysis_Case_Study_01', 'Velocity_Analysis_Case_Study_02', 'Velocity_Analysis_sweep'};
            app.KIN_01_Knob.ValueChangedFcn = createCallbackFcn(app, @KIN_01_KnobValueChanged, true);
            app.KIN_01_Knob.Value = 'Nothing';
            app.ChooseyourKinematictopicLabel = uilabel(app.KINEMATICSTab);
            app.ChooseyourKinematictopicLabel.Text = 'Choose your Kinematic topic';
            app.DYNAMICSTab = uitab(app.TabGroup);
            app.DYNAMICSTab.Title = 'DYNAMICS';
            app.DYN_01_Knob = uiknob(app.DYNAMICSTab, 'discrete');
            app.DYN_01_Knob.Items = {'Nothing', 'Dynamic_Derivation', 'Dynamic_Derivation_Alternate', 'Dynamic_Simulation_Model', 'Explore_Jacobians'};
            app.DYN_01_Knob.ValueChangedFcn = createCallbackFcn(app, @DYN_01_KnobValueChanged, true);
            app.DYN_01_Knob.Value = 'Nothing';
            app.ChooseyourDynamicstopicLabel = uilabel(app.DYNAMICSTab);
            app.ChooseyourDynamicstopicLabel.Text = 'Choose your Dynamics topic';
            app.APPSTab = uitab(app.TabGroup);
            app.APPSTab.Title = 'APPS';
            app.TabGroup2 = uitabgroup(app.APPSTab);
            app.THE_KINEMATICS_APPTab = uitab(app.TabGroup2);
            app.THE_KINEMATICS_APPTab.Title = 'THE_KINEMATICS_APP';
            app.TheKinematicsAPPButton = uibutton(app.THE_KINEMATICS_APPTab, 'push');
            app.TheKinematicsAPPButton.ButtonPushedFcn = createCallbackFcn(app, @TheKinematicsAPPButtonPushed, true);
            app.TheKinematicsAPPButton.Text = 'The Kinematics APP';
            app.Image2 = uiimage(app.THE_KINEMATICS_APPTab);
            app.Image2.ImageSource = 'bh_explore_app_SNAPSHOT.PNG';
            app.ExploretheAngularkinematicsofthe4barMechanismLabel = uilabel(app.THE_KINEMATICS_APPTab);
            app.ExploretheAngularkinematicsofthe4barMechanismLabel.Text = {'Explore the Angular kinematics'; 'of the 4bar Mechanism '};
            app.Lamp_APP_progress_1 = uilamp(app.THE_KINEMATICS_APPTab);
            app.Lamp_APP_progress_2 = uilamp(app.THE_KINEMATICS_APPTab);
            app.Lamp_APP_progress_3 = uilamp(app.THE_KINEMATICS_APPTab);
            app.Lamp_APP_progress_4 = uilamp(app.THE_KINEMATICS_APPTab);
            app.Lamp_APP_progress_5 = uilamp(app.THE_KINEMATICS_APPTab);
            % Show the figure after all components are created
            app.bh_4bar_content_APPUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bh_4bar_demo_launcher_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.bh_4bar_content_APPUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.bh_4bar_content_APPUIFigure)
        end
    end
end