classdef bh_pole_wheel_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PoleWheelDEMOUIFigure   matlab.ui.Figure
        EditField               matlab.ui.control.EditField
        TabGroup                matlab.ui.container.TabGroup
        WELCOMETab              matlab.ui.container.Tab
        Label                   matlab.ui.control.Label
        Label_2                 matlab.ui.control.Label
        Label_3                 matlab.ui.control.Label
        Image                   matlab.ui.control.Image
        PlayamovieButton_SPH_2  matlab.ui.control.Button
        STANDARD_EulerTab       matlab.ui.container.Tab
        Knob_SPH                matlab.ui.control.DiscreteKnob
        TextArea                matlab.ui.control.TextArea
        UIAxes_SPH              matlab.ui.control.UIAxes
        MODIFIED_EulerTab       matlab.ui.container.Tab
        Knob_UNI                matlab.ui.control.DiscreteKnob
        TextArea_2              matlab.ui.control.TextArea
    end

    properties (Access = public)
        OBJ_bh_DS % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.OBJ_bh_DS = bh_pole_wheel_demo_selector_CLS();
            
            app.OBJ_bh_DS.plot_app_logo_pic(app.UIAxes_SPH);
        end

        % Value changed function: Knob_SPH
        function Knob_SPHValueChanged(app, event)
            value = app.Knob_SPH.Value;
           
            switch(upper(value))
                case {'OFF'}
                        % do NOTHING
                case {'DERIVE_EOM'}
                        app.OBJ_bh_DS.task_derive_eom_se();
                case {'SCRIPT_PARAMS'}
                        app.OBJ_bh_DS.task_script_model_params();
                case {'MODEL_HAND_DERIVE'}
                        app.OBJ_bh_DS.task_model_se_hand();                        
                case {'MODEL_SIMSCAPE'}
                        app.OBJ_bh_DS.task_model_simscape();                               
                otherwise                  
                    error('###_ERROR: UNknown DEMO wheel choice !');
            end            
            
            
        end

        % Value changed function: Knob_UNI
        function Knob_UNIValueChanged(app, event)
            value = app.Knob_UNI.Value;
           
            switch(upper(value))
                case {'OFF'}
                        % do NOTHING
                case {'DERIVE_EOM'}
                        app.OBJ_bh_DS.task_derive_eom_me();
                case {'MODEL_HAND_DERIVE'}
                        app.OBJ_bh_DS.task_model_me_hand();                        
                case {'MODEL_SIMSCAPE'}
                        app.OBJ_bh_DS.task_model_simscape();                            
                otherwise                  
                    error('###_ERROR: UNknown DEMO wheel choice !');
            end                        
        end

        % Button pushed function: PlayamovieButton_SPH_2
        function PlayamovieButton_SPHPushed(app, event)
            app.OBJ_bh_DS.task_button_play_movie("SPHERICAL_CLIP"); 
        end

        % Callback function
        function PlayamovieButton_UNIPushed(app, event)
            app.OBJ_bh_DS.task_button_play_movie("UNIVERSAL_CLIP"); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PoleWheelDEMOUIFigure = uifigure('Visible', 'off');
            app.PoleWheelDEMOUIFigure.Name = 'PoleWheel DEMO';
            app.EditField = uieditfield(app.PoleWheelDEMOUIFigure, 'text');
            app.EditField.Value = 'Wheel on a Pole DEMO Selector';
            app.TabGroup = uitabgroup(app.PoleWheelDEMOUIFigure);
            app.WELCOMETab = uitab(app.TabGroup);
            app.WELCOMETab.Title = 'WELCOME';
            app.Label = uilabel(app.WELCOMETab);
            app.Label.Text = {'In this example we''ll study the equations of motion of the classic Wheel on a pole.'; ''; 'We''ll implement the dynamic equations of motion using 2 techniques:'};
            app.Label_2 = uilabel(app.WELCOMETab);
            app.Label_2.Text = {'a.)   the STANDARD Euler representation'; 'b.)   the MODIFIED   Euler representation'};
            app.Label_3 = uilabel(app.WELCOMETab);
            app.Label_3.Text = 'Welcome';
            app.Image = uiimage(app.WELCOMETab);
            app.Image.ImageSource = 'bh_PIC_pole_wheel.JPG';
            app.PlayamovieButton_SPH_2 = uibutton(app.WELCOMETab, 'push');
            app.PlayamovieButton_SPH_2.ButtonPushedFcn = createCallbackFcn(app, @PlayamovieButton_SPHPushed, true);
            app.PlayamovieButton_SPH_2.Text = 'Play a movie';
            app.STANDARD_EulerTab = uitab(app.TabGroup);
            app.STANDARD_EulerTab.Title = 'STANDARD_Euler';
            app.Knob_SPH = uiknob(app.STANDARD_EulerTab, 'discrete');
            app.Knob_SPH.Items = {'OFF', 'derive_EOM', 'script_params', 'Model_hand_derive', 'Model_simscape'};
            app.Knob_SPH.ValueChangedFcn = createCallbackFcn(app, @Knob_SPHValueChanged, true);
            app.Knob_SPH.Value = 'OFF';
            app.TextArea = uitextarea(app.STANDARD_EulerTab);
            app.TextArea.Value = {'Explore system involving a SPHERICAL joint at the pivot point.'};
            app.UIAxes_SPH = uiaxes(app.STANDARD_EulerTab);
            app.MODIFIED_EulerTab = uitab(app.TabGroup);
            app.MODIFIED_EulerTab.Title = 'MODIFIED_Euler';
            app.Knob_UNI = uiknob(app.MODIFIED_EulerTab, 'discrete');
            app.Knob_UNI.Items = {'OFF', 'derive_EOM', 'Model_hand_derive', 'Model_simscape'};
            app.Knob_UNI.ValueChangedFcn = createCallbackFcn(app, @Knob_UNIValueChanged, true);
            app.Knob_UNI.Value = 'OFF';
            app.TextArea_2 = uitextarea(app.MODIFIED_EulerTab);
            app.TextArea_2.Value = {'MODIFIED Euler:'; 'Explore system involving a SPHERICAL joint at the pivot point.'};
            % Show the figure after all components are created
            app.PoleWheelDEMOUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bh_pole_wheel_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PoleWheelDEMOUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PoleWheelDEMOUIFigure)
        end
    end
end